classdef GUI_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        OpenButton                  matlab.ui.control.Button
        Image                       matlab.ui.control.UIAxes
        resX                        matlab.ui.control.NumericEditField
        resY                        matlab.ui.control.NumericEditField
        XLabel                      matlab.ui.control.Label
        YLabel                      matlab.ui.control.Label
        ResolutionLabel             matlab.ui.control.Label
        ImageFileLabel              matlab.ui.control.Label
        ComPortEditFieldLabel       matlab.ui.control.Label
        ComPort                     matlab.ui.control.EditField
        CenterPanel                 matlab.ui.container.Panel
        convertButton               matlab.ui.control.Button
        PrimaryDropDownLabel        matlab.ui.control.Label
        PrimaryDropDown             matlab.ui.control.DropDown
        SecondaryDropDownLabel      matlab.ui.control.Label
        SecondaryDropDown           matlab.ui.control.DropDown
        EnableSecondarySwitchLabel  matlab.ui.control.Label
        EnableSecondarySwitch       matlab.ui.control.Switch
        RightPanel                  matlab.ui.container.Panel
        pathImage                   matlab.ui.control.UIAxes
        StartButton                 matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        rect % Description
        rawImage
        mat % Description
    end
    
    methods (Access = private)
%       unique feature is the fact that you can select 2 different algos
        function [xmin, xmax, ymin, ymax] = pos(app)
            xmin = round( app.rect.Position(1,1) );
            xmax = round( app.rect.Position(1,1)+app.rect.Position(1,3) );
            
            ymax = round( app.resY.Value - app.rect.Position(1,2) );
            ymin = round( app.resY.Value  - app.rect.Position(1,2) - app.rect.Position(1,4) );
        end
        
        function primary = filterPrimary(app,mat)
            [xmin, xmax, ymin, ymax] = app.pos();
             newMat = [0 0];
             for i=1:length(mat)
                 p = mat(i,:);
                 if ~(p(1,1) > xmin && p(1,1) < xmax && p(1,2) > ymin && p(1,2) < ymax ) 
                     newMat(end+1, :) = p;
                 end
             end
             primary = newMat; 
        end
        
        function secondary = fitlerSecondary(app,mat)
            [xmin, xmax, ymin, ymax] = app.pos();
            app.rect
             newMat = [0 0];
             for i=1:length(mat)
                 p = mat(i,:);
                 if p(1,1) > xmin && p(1,1) < xmax && p(1,2) > ymin && p(1,2) < ymax 
                     newMat(end+1, :) = p;
                 end
             end
             secondary = newMat; 
        end
        
        function primary = algo(app, value, img)
            primary = [];
            switch value
                case 'Outline'
                     primary = image_edges(img);
                case 'Shading'
                     primary = shade(img);
                case 'Boxes'
                     primary = boxes(img);
            end
        end
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenButton
        function getFile(app, event)
            [file,path,index] = uigetfile('*');
            if isequal(file,0)
               disp('User selected Cancel')
            else
                app.OpenButton.Text = file;
                img = imread(fullfile(path,file));
                app.rawImage = imresize(img,[app.resY.Value,app.resX.Value]);
                imagesc(app.Image, app.rawImage);
                app.rect=drawrectangle(app.Image,'Position',[300 300 200 200]);
            end
        end

        % Button pushed function: convertButton
        function convert(app, event)
            cla(app.pathImage) % clear the path img
            
            
            primary = app.algo(app.PrimaryDropDown.Value, app.rawImage);
       
            app.mat = primary;
            
            if app.EnableSecondarySwitch.Value == 1
                primary = app.filterPrimary(primary);
                img = imcrop(app.rawImage,app.rect.Position);
                secondary = app.algo(app.SecondaryDropDown.Value, img);
                [xmin, xmax, ymin] = app.pos();
                secondary(:,1) =  secondary(:,1)  + xmin;
                secondary(:,2) =  secondary(:,2)  + ymin;
              
                app.mat = [primary; secondary];
                
            end
           
            axis (app.pathImage, [0  app.resX.Value 0 app.resY.Value]) %Your x values  should  be  between 0 and  875.  You y values  shouldbe  between 0 and  640
            h=animatedline(app.pathImage);
            for i=1: length(app.mat)               
                addpoints(h,app.mat(i,1),app.mat(i,2))
            end
            
        end

        % Value changed function: resX, resY
        function resChange(app, event)
            
           pos = abs(app.rect.Position);
           app.rawImage = imresize(app.rawImage,[app.resY.Value,app.resX.Value]);
           imagesc(app.Image, app.rawImage);
           app.rect=drawrectangle(app.Image,'Position',pos);
            
        end

        % Button pushed function: StartButton
        function start(app, event)
            
            basecode(app.mat,app.ComPort.Value)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {622, 622, 622};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {622, 622};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {350, '1x', 632};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.OpenButton = uibutton(app.LeftPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @getFile, true);
            app.OpenButton.Text = 'Open';
            app.Image = uiaxes(app.LeftPanel);
            title(app.Image, 'Image')
            xlabel(app.Image, '')
            ylabel(app.Image, '')
            app.Image.XTick = [0 100 200 300 400 500 600 700 800];
            app.Image.XTickLabel = {'0'; '100'; '200'; '300'; '400'; '500'; '600'; '700'; '900'};
            app.Image.YTick = [0 100 200 300 400 500 600 700 900];
            app.Image.YTickLabel = {'0'; '100'; '200'; '300'; '400'; '500'; '600'; '700'; '900'};
            app.resX = uieditfield(app.LeftPanel, 'numeric');
            app.resX.ValueChangedFcn = createCallbackFcn(app, @resChange, true);
            app.resX.Value = 875;
            app.resY = uieditfield(app.LeftPanel, 'numeric');
            app.resY.ValueChangedFcn = createCallbackFcn(app, @resChange, true);
            app.resY.Value = 640;
            app.XLabel = uilabel(app.LeftPanel);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.LeftPanel);
            app.YLabel.Text = 'Y';
            app.ResolutionLabel = uilabel(app.LeftPanel);
            app.ResolutionLabel.Text = 'Resolution';
            app.ImageFileLabel = uilabel(app.LeftPanel);
            app.ImageFileLabel.Text = 'Image File';
            app.ComPortEditFieldLabel = uilabel(app.LeftPanel);
            app.ComPortEditFieldLabel.Text = 'Com Port';
            app.ComPort = uieditfield(app.LeftPanel, 'text');
            app.ComPort.Value = '/dev/cu.usbmodem14101';
            app.CenterPanel = uipanel(app.GridLayout);
            app.convertButton = uibutton(app.CenterPanel, 'push');
            app.convertButton.ButtonPushedFcn = createCallbackFcn(app, @convert, true);
            app.convertButton.Text = 'convert';
            app.PrimaryDropDownLabel = uilabel(app.CenterPanel);
            app.PrimaryDropDownLabel.Text = 'Primary';
            app.PrimaryDropDown = uidropdown(app.CenterPanel);
            app.PrimaryDropDown.Items = {'Outline', 'Shading', 'Boxes', ''};
            app.PrimaryDropDown.Value = 'Outline';
            app.SecondaryDropDownLabel = uilabel(app.CenterPanel);
            app.SecondaryDropDownLabel.Text = {'Secondary'; ''; ''};
            app.SecondaryDropDown = uidropdown(app.CenterPanel);
            app.SecondaryDropDown.Items = {'Outline', 'Shading', 'Boxes', ''};
            app.SecondaryDropDown.Value = 'Shading';
            app.EnableSecondarySwitchLabel = uilabel(app.CenterPanel);
            app.EnableSecondarySwitchLabel.Text = 'Enable Secondary ';
            app.EnableSecondarySwitch = uiswitch(app.CenterPanel, 'slider');
            app.RightPanel = uipanel(app.GridLayout);
            app.pathImage = uiaxes(app.RightPanel);
            title(app.pathImage, '')
            xlabel(app.pathImage, '')
            ylabel(app.pathImage, '')
            app.StartButton = uibutton(app.RightPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @start, true);
            app.StartButton.Text = 'Start!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end